classdef UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        StartSimulationButton       matlab.ui.control.StateButton
        ResetButton                 matlab.ui.control.Button
        InitialAngledegSliderLabel  matlab.ui.control.Label
        InitialAngledegSlider       matlab.ui.control.Slider
        KvSpinnerLabel              matlab.ui.control.Label
        KvSpinner                   matlab.ui.control.Spinner
        KxSpinnerLabel              matlab.ui.control.Label
        KxSpinner                   matlab.ui.control.Spinner
        KthetaSpinnerLabel          matlab.ui.control.Label
        KthetaSpinner               matlab.ui.control.Spinner
        KomegaSpinnerLabel          matlab.ui.control.Label
        KomegaSpinner               matlab.ui.control.Spinner
        TextArea                    matlab.ui.control.TextArea
        ControlModeButtonGroup      matlab.ui.container.ButtonGroup
        ArrowKeyOnlyButton          matlab.ui.control.RadioButton
        PControllerButton           matlab.ui.control.RadioButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Selection changed function: ControlModeButtonGroup
        function ControlModeButtonGroupSelectionChanged(app, event)
            selectedButton = app.ControlModeButtonGroup.SelectedObject;
            
            if selectedButton.Text == "Arrow Key Only"
                setPControllerVisibility(app,"off");
            else
                setPControllerVisibility(app,"on");
            end
            
            % A helper function to set the Visibility of P controller
            % setters.
            function setPControllerVisibility(app,on_off_flag)
                tohide = [app.KvSpinner,app.KvSpinnerLabel,...
                      app.KomegaSpinner,app.KomegaSpinnerLabel,...
                      app.KthetaSpinner,app.KthetaSpinnerLabel,...
                      app.KxSpinner,app.KxSpinnerLabel];
                for h = tohide
                    h.Visible = on_off_flag;
                    h.Enable = on_off_flag;
                end
            end
        end

        % Value changed function: StartSimulationButton
        function StartSimulationButtonValueChanged(app, event)
            value = app.StartSimulationButton.Value;
            if value==1
                app.InitialAngledegSlider.Enable='off';
                app.InitialAngledegSliderLabel.Enable='off';
                app.StartSimulationButton.Text = 'Press to Pause';
            else
                app.StartSimulationButton.Text = 'Start Simulation';
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.InitialAngledegSlider.Enable='on';
            app.InitialAngledegSliderLabel.Enable='on';
            app.StartSimulationButton.Value = 0;
            % Manually call the simulation button changed event. [] means
            % an empty event.
            StartSimulationButtonValueChanged(app,[]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartSimulationButton = uibutton(app.UIFigure, 'state');
            app.StartSimulationButton.ValueChangedFcn = createCallbackFcn(app, @StartSimulationButtonValueChanged, true);
            app.StartSimulationButton.Text = 'Start Simulation';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.InitialAngledegSliderLabel = uilabel(app.UIFigure);
            app.InitialAngledegSliderLabel.Text = 'Initial Angle(deg)';
            app.InitialAngledegSlider = uislider(app.UIFigure);
            app.InitialAngledegSlider.Limits = [0 360];
            app.KvSpinnerLabel = uilabel(app.UIFigure);
            app.KvSpinnerLabel.Enable = 'off';
            app.KvSpinnerLabel.Visible = 'off';
            app.KvSpinnerLabel.Text = 'Kv';
            app.KvSpinner = uispinner(app.UIFigure);
            app.KvSpinner.Enable = 'off';
            app.KvSpinner.Visible = 'off';
            app.KxSpinnerLabel = uilabel(app.UIFigure);
            app.KxSpinnerLabel.Enable = 'off';
            app.KxSpinnerLabel.Visible = 'off';
            app.KxSpinnerLabel.Text = 'Kx ';
            app.KxSpinner = uispinner(app.UIFigure);
            app.KxSpinner.Enable = 'off';
            app.KxSpinner.Visible = 'off';
            app.KthetaSpinnerLabel = uilabel(app.UIFigure);
            app.KthetaSpinnerLabel.Enable = 'off';
            app.KthetaSpinnerLabel.Visible = 'off';
            app.KthetaSpinnerLabel.Text = 'Ktheta';
            app.KthetaSpinner = uispinner(app.UIFigure);
            app.KthetaSpinner.Enable = 'off';
            app.KthetaSpinner.Visible = 'off';
            app.KomegaSpinnerLabel = uilabel(app.UIFigure);
            app.KomegaSpinnerLabel.Enable = 'off';
            app.KomegaSpinnerLabel.Visible = 'off';
            app.KomegaSpinnerLabel.Text = 'Komega';
            app.KomegaSpinner = uispinner(app.UIFigure);
            app.KomegaSpinner.Enable = 'off';
            app.KomegaSpinner.Visible = 'off';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Press Left/Right Arrow Key to Directly Apply Forces on the Cart Body'};
            app.ControlModeButtonGroup = uibuttongroup(app.UIFigure);
            app.ControlModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ControlModeButtonGroupSelectionChanged, true);
            app.ControlModeButtonGroup.Title = 'Control Mode';
            app.ArrowKeyOnlyButton = uiradiobutton(app.ControlModeButtonGroup);
            app.ArrowKeyOnlyButton.Text = 'Arrow Key Only';
            app.ArrowKeyOnlyButton.Value = true;
            app.PControllerButton = uiradiobutton(app.ControlModeButtonGroup);
            app.PControllerButton.Text = 'P Controller';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end